classdef SimulacionF1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        btnGradas                  matlab.ui.control.CheckBox
        slRapidez                  matlab.ui.control.Slider
        RapidezdesimulacinsLabel   matlab.ui.control.Label
        txtCalor                   matlab.ui.control.NumericEditField
        TiempoLabel_2              matlab.ui.control.Label
        txtTiempo                  matlab.ui.control.NumericEditField
        TiempoLabel                matlab.ui.control.Label
        txtAceleracion             matlab.ui.control.NumericEditField
        AceleracinLabel            matlab.ui.control.Label
        txtY                       matlab.ui.control.NumericEditField
        YLabel                     matlab.ui.control.Label
        txtX                       matlab.ui.control.NumericEditField
        XLabel                     matlab.ui.control.Label
        txtVelocidad               matlab.ui.control.NumericEditField
        VelocidadEditFieldLabel    matlab.ui.control.Label
        VelocidadkmhLabel          matlab.ui.control.Label
        btnComenzar                matlab.ui.control.Button
        RecorridoButtonGroup       matlab.ui.container.ButtonGroup
        btnSalida                  matlab.ui.control.RadioButton
        btnNormal                  matlab.ui.control.RadioButton
        txtVelocidadI              matlab.ui.control.NumericEditField
        txtEstatus                 matlab.ui.control.EditField
        EstatusEditFieldLabel      matlab.ui.control.Label
        PosicinLabel               matlab.ui.control.Label
        SimulacinVehculoenF1Label  matlab.ui.control.Label
        Grafica                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
    end
    
    methods (Access = private)
        
       
        function tangente = encontrarTangente(app, x,y,dx)
                
            %y = mx+b
                 xT = [500:900];
                 b = y - dx*x;
                 yT =  dx.*xT+b;
                 plot(app.Grafica, xT,yT)
                 hold on
            
                 tangente = x;
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnComenzar
        function btnComenzarButtonPushed(app, event)
            app.txtEstatus.Value = "Recorriendo circuito";

            %Puntos
            x1 = 100;
            y1 = 2900;
            
            x2 = 2800;
            y2 = 2400;
            
            
            x3 = 400;
            y3 = 1510;
            
            x4 = 1170;
            y4 = 2400;
            
                        
            % Definición de vectores
            y = [y1; y2; y3; y4];
            A = [x1^3 x1^2 x1 1; x2^3 x2^2 x2 1; x3^3 x3^2 x3 1; x4^3 x4^2 x4 1];
            coef = inv(A)*y;
            
            % Vectores para la gráfica
             xf = [0:3000];
             yf = coef(1).* xf .^3 + coef(2).* xf .^2 + coef(3).* xf + coef(4);
            
             % Marcar los puntos iniciales
             xlim(app.Grafica, [0, 3000])
             ylim(app.Grafica, [0,6000])

             plot(app.Grafica, 100,2900, "ro")
             hold on
             plot(app.Grafica, 2800,2400, "ro")
             hold on
            
             % Marcar los puntos agregados
             plot(app.Grafica, x3,y3,"bo")
             hold on
             plot(app.Grafica, x4,y4,"bo")
             hold on
            
             % Grafica de pista
             plot(app.Grafica, xf, yf,"k")
                
             
             % Cálculos
             
             %Definir la ecuación
                x = sym('x');
                f(x) = coef(1)*x.^3 + coef(2)*x.^2 + coef(3)*x + coef(4);
            
             %Valores min y max
               xMin = fminsearch(f,x1);
 
            
             %Derivadas
               derivada = @(x)  3*coef(1)*x.^2 + 2*coef(2)*x + coef(3);
               segundaDerivada = @(x) 6*coef(1)*x + 2*coef(2);
            
             %Cálculo del radio (150)
               xI = 560;
               radio = ((1+derivada(xMin)^2)^(3/2))/segundaDerivada(xMin);
            
             %Cálculo de longitud
               expresion = @(x) sqrt(1+(3*coef(1)*x.^2 + 2*coef(2)*x + coef(3)).^2);
               longitud = integral(expresion,100,2800);
         
               dx = derivada(xI);
                yI = f(xI);

             %Gráfica de las gradas
             %Primera normal
               % x0= [500,600];
               
                n = -(1/dx);
            
                x3 = [534,544];
                bn = yI - (n*xI);
            
                y3 = n.*x3+bn;
               % y0 = n.*x0+bn;
                hold(app.Grafica, "on")
                plot(app.Grafica, x3, y3,"LineWidth", 2)
                
            
               % plot(app.Grafica, x0, y0);
               % hold on
            
                %Segunda normal
                x4 = [576,587];
            
                bn2 = 1238.69 - (n*602.68);
                y4 = n.*x4+bn2;
                plot(app.Grafica, x4, y4, "LineWidth", 2);
                hold(app.Grafica, "on")
            
                %Primera tangente
                 x2 = [544,587];
                 b = 1239 - dx*544.2;
                 y2 =  dx.*x2+b;
                 plot(app.Grafica, x2,y2, "LineWidth", 2)
                hold(app.Grafica, "on")
            
                 %Segunda tangente
                 x2 = [533,576];
                 b = 1222 - dx*533.7;
                 y2 =  dx.*x2+b;
                 plot(app.Grafica, x2,y2, "LineWidth", 2)
                hold(app.Grafica, "on")


               %Animación
                curve = animatedline(app.Grafica, 'marker','*','Color','r');
                xPoint = 0;
                velocidad = app.txtVelocidadI.Value/3.6;
                
                tiempo = 0;
                app.txtTiempo.Value = tiempo;
                normal = app.btnNormal.Value;

                %Energia (esto esta mal)
                masa = 798;
                energiaTotal = (1/2)*masa*velocidad

                %En teoria aquí va el cálculo de la vel Max
                velocidadMax = 3.6*sqrt(9.81*radio*1.7);

                app.txtCalor.Value = 0;

                %Simulación del carro -------------------------------------
                while xPoint < 3000
                    %Contador de tiempo
                    tiempo = tiempo + 1;
                   
                    dt = 0.5;

                    %Aceleración (dato inventaado)
                    aceleracion = 30;
                       
                    %Se calcula el radio
                    radio = ((1+derivada(xPoint)^2)^(3/2))/segundaDerivada(xPoint)
                    
                    %Se calcula el ángulo 
                    angulo = atan(derivada(xPoint));
                    

                    %----En caso de que el radio sea menor a 150 y se quiera el recorrido de salida---
                    
                    if (app.btnSalida.Value == 1 && radio <150)
                        yPoint = double(f(xPoint));
                            
                        app.txtEstatus.Value = "Peligro, el carro ha salido de la pista";
                        app.txtEstatus.FontColor = "red";

                        %Ecuacion de la tangente
                        dx = derivada(xPoint);
                        b = yPoint - dx*xPoint;
                        fTan(x) = dx*x + b;


                        % Recorrido por la tangente
                        while (xPoint < 800)

                        angulo2 = atan(segundaDerivada(xPoint));
                        xPoint = xPoint + r*cos(angulo2);
                        yPoint = double(fTan(xPoint));
                        clearpoints(curve)
                        addpoints(curve,xPoint,yPoint);
                        drawnow
                        pause(app.slRapidez.Value)

                        end
                        break
                    end

                    %En caso de que se llegue al radio, la velocidad
                    %disminuye para la vuelta
                    if ((radio < 170 && xPoint < 620) || (radio < 0 && radio >-170 && xPoint < 2170))
                        r = 0.1*velocidad*dt
                        app.txtVelocidad.Value = 0.1*velocidad*3.6;
                        app.txtAceleracion.Value = aceleracion;
                        energia = energiaTotal - (1/2)*masa*0.1*velocidad;
                        app.txtCalor.Value = energia;

                        
                    else
                        r = velocidad*dt
                        app.txtVelocidad.Value = 3.6*velocidad;
                        app.txtAceleracion.Value = 0;


                    end

                    %Se grafica el punto y actualiza la interfaz
                    app.txtTiempo.Value = tiempo;

                    xPoint = xPoint + r*cos(angulo);
                    app.txtX.Value = xPoint;


                    yPoint = double(f(xPoint));
                    app.txtY.Value = yPoint;

                    clearpoints(curve)
                     addpoints(curve,xPoint,yPoint);
                     drawnow
                     pause(app.slRapidez.Value)


                end

            
                hold off
            
            
                disp("radio: ")
                disp(radio)
                disp("longitud: ")
                disp(longitud)


        end

        % Value changed function: txtVelocidadI
        function txtVelocidadIValueChanged(app, event)
            value = app.txtVelocidadI.Value;
            if (value > 400)
                app.btnSalida.Value = 1;
            else
                app.btnNormal.Value = 1;
            end
        
        end

        % Selection changed function: RecorridoButtonGroup
        function RecorridoButtonGroupSelectionChanged(app, event)
            if (app.btnNormal.Value == 1)
                app.txtVelocidadI.Value = 250;
            else
                app.txtVelocidadI.Value = 400;
            end
        end

        % Value changed function: slRapidez
        function slRapidezValueChanged(app, event)
            value = app.slRapidez.Value;
            
        end

        % Value changed function: btnGradas
        function btnGradasValueChanged(app, event)
            value = app.btnGradas.Value;
            if (value == 1)
                xlim(app.Grafica, [300, 900])
                ylim(app.Grafica, [1000,2000])
            else
                xlim(app.Grafica, [0, 3000])
                ylim(app.Grafica, [0,6000])
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafica = uiaxes(app.UIFigure);
            title(app.Grafica, 'Circuito')
            xlabel(app.Grafica, 'X')
            ylabel(app.Grafica, 'Y')
            zlabel(app.Grafica, 'Z')
            app.SimulacinVehculoenF1Label = uilabel(app.UIFigure);
            app.SimulacinVehculoenF1Label.Text = 'Simulación: Vehículo en F1';
            app.PosicinLabel = uilabel(app.UIFigure);
            app.PosicinLabel.Text = 'Posición';
            app.EstatusEditFieldLabel = uilabel(app.UIFigure);
            app.EstatusEditFieldLabel.Text = 'Estatus';
            app.txtEstatus = uieditfield(app.UIFigure, 'text');
            app.txtEstatus.Editable = 'off';
            app.txtVelocidadI = uieditfield(app.UIFigure, 'numeric');
            app.txtVelocidadI.ValueChangedFcn = createCallbackFcn(app, @txtVelocidadIValueChanged, true);
            app.txtVelocidadI.Value = 250;
            app.RecorridoButtonGroup = uibuttongroup(app.UIFigure);
            app.RecorridoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RecorridoButtonGroupSelectionChanged, true);
            app.RecorridoButtonGroup.Title = 'Recorrido';
            app.btnNormal = uiradiobutton(app.RecorridoButtonGroup);
            app.btnNormal.Text = 'Normal';
            app.btnNormal.Value = true;
            app.btnSalida = uiradiobutton(app.RecorridoButtonGroup);
            app.btnSalida.Text = 'Salida de pista';
            app.btnComenzar = uibutton(app.UIFigure, 'push');
            app.btnComenzar.ButtonPushedFcn = createCallbackFcn(app, @btnComenzarButtonPushed, true);
            app.btnComenzar.Text = 'Comenzar';
            app.VelocidadkmhLabel = uilabel(app.UIFigure);
            app.VelocidadkmhLabel.Text = 'Velocidad (km/h)';
            app.VelocidadEditFieldLabel = uilabel(app.UIFigure);
            app.VelocidadEditFieldLabel.Text = 'Velocidad';
            app.txtVelocidad = uieditfield(app.UIFigure, 'numeric');
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.txtX = uieditfield(app.UIFigure, 'numeric');
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.txtY = uieditfield(app.UIFigure, 'numeric');
            app.AceleracinLabel = uilabel(app.UIFigure);
            app.AceleracinLabel.Text = 'Aceleración';
            app.txtAceleracion = uieditfield(app.UIFigure, 'numeric');
            app.TiempoLabel = uilabel(app.UIFigure);
            app.TiempoLabel.Text = 'Tiempo (s)';
            app.txtTiempo = uieditfield(app.UIFigure, 'numeric');
            app.TiempoLabel_2 = uilabel(app.UIFigure);
            app.TiempoLabel_2.Text = 'Pérdida de calor';
            app.txtCalor = uieditfield(app.UIFigure, 'numeric');
            app.RapidezdesimulacinsLabel = uilabel(app.UIFigure);
            app.RapidezdesimulacinsLabel.Text = 'Rapidez de simulación';
            app.slRapidez = uislider(app.UIFigure);
            app.slRapidez.Limits = [0.001 0.4];
            app.slRapidez.MajorTickLabels = {'2', '1.75', '1.5', '1.15', '1 '};
            app.slRapidez.ValueChangedFcn = createCallbackFcn(app, @slRapidezValueChanged, true);
            app.slRapidez.Value = 0.1;
            app.btnGradas = uicheckbox(app.UIFigure);
            app.btnGradas.ValueChangedFcn = createCallbackFcn(app, @btnGradasValueChanged, true);
            app.btnGradas.Text = 'Zoom en gradas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimulacionF1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end