classdef Simulador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        altura                      matlab.ui.control.NumericEditField
        AlturaLabel                 matlab.ui.control.Label
        masa1                       matlab.ui.control.NumericEditField
        MasaLabel                   matlab.ui.control.Label
        RetoMotordesimulacindemovimientoLabel  matlab.ui.control.Label
        slider                      matlab.ui.control.Slider
        ngulodeDisparoLabel         matlab.ui.control.Label
        k                           matlab.ui.control.NumericEditField
        kLabel                      matlab.ui.control.Label
        ProyectilSinFr_2            matlab.ui.container.Panel
        velZcon                     matlab.ui.control.NumericEditField
        VelocidadZEditFieldLabel_2  matlab.ui.control.Label
        posZcon                     matlab.ui.control.NumericEditField
        PosicionZEditFieldLabel     matlab.ui.control.Label
        posXcon                     matlab.ui.control.NumericEditField
        posYcon                     matlab.ui.control.NumericEditField
        velXcon                     matlab.ui.control.NumericEditField
        VelocidadXLabel_2           matlab.ui.control.Label
        velYcon                     matlab.ui.control.NumericEditField
        VelocidadYLabel_2           matlab.ui.control.Label
        PosicinYLabel_2             matlab.ui.control.Label
        PosicinXLabel_2             matlab.ui.control.Label
        ProyectilSinFr              matlab.ui.container.Panel
        velZsin                     matlab.ui.control.NumericEditField
        VelocidadZEditFieldLabel    matlab.ui.control.Label
        posZsin                     matlab.ui.control.NumericEditField
        PosicinZEditFieldLabel      matlab.ui.control.Label
        posXsin                     matlab.ui.control.NumericEditField
        posYsin                     matlab.ui.control.NumericEditField
        VelocidadYLabel             matlab.ui.control.Label
        velXsin                     matlab.ui.control.NumericEditField
        velYsin                     matlab.ui.control.NumericEditField
        PosicinYLabel               matlab.ui.control.Label
        PosicinXLabel               matlab.ui.control.Label
        VelocidadXLabel             matlab.ui.control.Label
        diametro                    matlab.ui.control.NumericEditField
        DimetroLabel                matlab.ui.control.Label
        GraficarButton              matlab.ui.control.Button
        angulo                      matlab.ui.control.NumericEditField
        ngulodedisparoLabel         matlab.ui.control.Label
        vInicial                    matlab.ui.control.NumericEditField
        VelocidadInicialLabel       matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        diam % Description
        angle % Description
        vel % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            
            %Código del volcán
            a = app.altura.Value;                           % Altura del volcán
            t = 0:0.5:1;                        % Matriz del tiempo
            color = '#8B4513';                  % Color del volcán
            [x_v, y_v, z_v] = cylinder(a * t);  % Fórmula del cono
    
            %Se inicia la gráfica
            comet3(app.UIAxes,0,0,0);
            xlim(app.UIAxes, [-3000, 8000])
            ylim(app.UIAxes, [-2000,2000])
            zlim(app.UIAxes, [-1000, 700])
            
            %Grafica del volcán
            hold(app.UIAxes,"on");
            surf(app.UIAxes,x_v,y_v,(-1*a*z_v), 'faceColor', color); 
            axis vis3d;

            %Variables iniciales
            masa = app.masa1.Value;             % masa del proyectil
            diametro1 = app.diametro.Value;     % diametro del proyectil
            k1 = app.k.Value;                   % coeficiente de arrastre
            b = diametro1*(k1*(10^-4));         % arrastre
            anguloG = app.angulo.Value;         % anguo de lanzamiento
            anguloR = anguloG/180;              % angulo en radianes
            dt = 1;                             % delta t
            gravedad = 9.81;                    % gravedad
            vi = app.vInicial.Value;            % velocidad inicial

            viz = vi*sin(anguloR);              %velocidad inicial en z
            anguloPlanoG = app.slider.Value;    %angulo en z
            anguloPlanoR = anguloPlanoG*pi/180; %angulo en z en radianes

            
            %Cálculo del tiempo que toma el proyectil en caer 
            caida = [-9.81/2 viz 1000]; %Basado en ecuación de cinemática 
            tCaida = roots(caida);      %Tiempos en llegar al piso

            %Se busca el valor positivo de tiempo n=numero de iteraciones
            if(tCaida(1) > 0)
                 n = round(tCaida(1)) 
            elseif(tCaida(2) > 0)
                 n = round(tCaida(2))
            else
                 n=25 
            end
            

            %%
            %Gráfica con fricción

            %Vectores
            vir = vi*cos(anguloR);  %velocidad inicial en x
            ri = 0;                 %posicion en x

            vr = 1:dt:n;            %vector velocidad en x
            vr(1) = vir;            %vi

            r = 1:dt:n;             %vector posicion en x
            r(1) = ri;              %ri

            fr = 1:dt:n;            %vector fuerza en x
            fr(1) = -1*b*vir;       %fri

            ar = 1:dt:n;            %vector aceleracion en x
            ar(1) = fr(1)/masa;     %ari

            zi = 0;                 %posicion inicial en y

            vz = 1:dt:n;            %vector velocidad en y
            vz(1) = viz;

            z = 1:dt:n;             %vector posición en y
            z(1) = zi;

            fz = 1:dt:n;            %vector fuerza en y
            fz(1) = -1*b*viz-(masa*gravedad);

            az = 1:dt:n;            %vector aceleración en y
            az(1) = fz(1)/masa;


            %Siguiendo el método euler, se llenan los vectores
            for i=2:n
                % r
                vr(i)=vr(i-1)+(ar(i-1)*dt);
                r(i) = r(i-1)+(dt*vr(i));
                fr(i) = -1*b*vr(i);
                ar(i)= fr(i)/masa;
                % z
                vz(i)=vz(i-1)+(az(i-1)*dt);
                z(i) = z(i-1)+(dt*vz(i));
                fz(i) = -1*b*vz(i)-(masa*gravedad);
                az(i)= fz(i)/masa;
            end
        
            %%
            %Gráfica sin fricción

            %Vectores
            t1= 1:dt:n;             %vector tiempo
            vrn = vir;              %velocidad en x sin fricción

            rn = 1:dt:n;            %posición en x 
            rn(1) = ri;             %ri

            vzn = 1:dt:n;           %velocidad en y sin fracción
            vzn(1) = viz;           %viz

            zn = 1:dt:n;            %posición en y
            zn(1) = zi;             %zi

            %Se llenan los vectores usando los tiempos
            for i=2:n 
                rn(i) = ri+(vrn*t1(i));
                vzn(i)=viz-gravedad*t1(i);
                zn(i) = zi+((vzn(i)+viz)/2)*t1(i);

            end

            %%
            %Coordenadas x e y

            x = r.*cos(anguloPlanoR);
            y = r.*sin(anguloPlanoR);

            xn = rn*cos(anguloPlanoR);
            yn = rn*sin(anguloPlanoR);

            vx = vr*cos(anguloPlanoR);
            vy = vr*sin(anguloPlanoR);

            vxn = vrn*cos(anguloPlanoR);
            vyn = vrn*sin(anguloPlanoR);


            %Se actualizan los valores en la interfaz     
            app.posYsin.Value = yn(end);
            app.posZsin.Value = zn(end);
            app.posXsin.Value = xn(end);
            app.velXsin.Value = vxn(end);
            app.velYsin.Value = vyn(end);
         

            app.posYcon.Value = y(end);
            app.posZcon.Value = z(end);
            app.posXcon.Value = x(end);
            app.velXcon.Value = vx(end);
            app.velYcon.Value = vy(end);
            app.velZcon.Value = vz(end);
      

            %Se grafica
            hold(app.UIAxes,"on")
            comet3(app.UIAxes,x,y,z)
            comet3(app.UIAxes,xn,yn,zn)


        end

        % Value changed function: diametro
        function diametroValueChanged(app, event)
            value = app.diametro.Value;
            app.diam = [value; app.diam];

        end

        % Value changed function: angulo
        function anguloValueChanged(app, event)
            value = app.angulo.Value;
            app.angle = [value; app.angle];
        end

        % Value changed function: vInicial
        function vInicialValueChanged(app, event)
            value = app.vInicial.Value;
            app.vel = [value; app.vel];
        end

        % Value changed function: velXsin
        function velXsinValueChanged(app, event)
            value = app.GraficarButton.Value;
            
        end

        % Value changed function: posXsin
        function posXsinValueChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trayectoria de proyectiles')
            xlabel(app.UIAxes, 'Posición x (m)')
            ylabel(app.UIAxes, 'Posición y (m)')
            zlabel(app.UIAxes, 'Altura (m)')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'tec.jpeg');
            app.VelocidadInicialLabel = uilabel(app.UIFigure);
            app.VelocidadInicialLabel.Text = 'Velocidad Inicial:';
            app.vInicial = uieditfield(app.UIFigure, 'numeric');
            app.vInicial.ValueChangedFcn = createCallbackFcn(app, @vInicialValueChanged, true);
            app.ngulodedisparoLabel = uilabel(app.UIFigure);
            app.ngulodedisparoLabel.Text = 'Ángulo de disparo:';
            app.angulo = uieditfield(app.UIFigure, 'numeric');
            app.angulo.ValueChangedFcn = createCallbackFcn(app, @anguloValueChanged, true);
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.DimetroLabel = uilabel(app.UIFigure);
            app.DimetroLabel.Text = 'Diámetro:';
            app.diametro = uieditfield(app.UIFigure, 'numeric');
            app.diametro.ValueChangedFcn = createCallbackFcn(app, @diametroValueChanged, true);
            app.ProyectilSinFr = uipanel(app.UIFigure);
            app.ProyectilSinFr.Title = 'Proyectil sin Fricción';
            app.VelocidadXLabel = uilabel(app.ProyectilSinFr);
            app.VelocidadXLabel.Text = 'Velocidad X';
            app.PosicinXLabel = uilabel(app.ProyectilSinFr);
            app.PosicinXLabel.Text = 'Posición X';
            app.PosicinYLabel = uilabel(app.ProyectilSinFr);
            app.PosicinYLabel.Text = 'Posición Y';
            app.velYsin = uieditfield(app.ProyectilSinFr, 'numeric');
            app.velXsin = uieditfield(app.ProyectilSinFr, 'numeric');
            app.velXsin.ValueChangedFcn = createCallbackFcn(app, @velXsinValueChanged, true);
            app.VelocidadYLabel = uilabel(app.ProyectilSinFr);
            app.VelocidadYLabel.Text = 'Velocidad Y';
            app.posYsin = uieditfield(app.ProyectilSinFr, 'numeric');
            app.posXsin = uieditfield(app.ProyectilSinFr, 'numeric');
            app.posXsin.ValueChangedFcn = createCallbackFcn(app, @posXsinValueChanged, true);
            app.PosicinZEditFieldLabel = uilabel(app.ProyectilSinFr);
            app.PosicinZEditFieldLabel.Text = 'Posición Z';
            app.posZsin = uieditfield(app.ProyectilSinFr, 'numeric');
            app.VelocidadZEditFieldLabel = uilabel(app.ProyectilSinFr);
            app.VelocidadZEditFieldLabel.Text = 'Velocidad Z';
            app.velZsin = uieditfield(app.ProyectilSinFr, 'numeric');
            app.ProyectilSinFr_2 = uipanel(app.UIFigure);
            app.ProyectilSinFr_2.Title = 'Proyectil con Fricción';
            app.PosicinXLabel_2 = uilabel(app.ProyectilSinFr_2);
            app.PosicinXLabel_2.Text = 'Posición X';
            app.PosicinYLabel_2 = uilabel(app.ProyectilSinFr_2);
            app.PosicinYLabel_2.Text = 'Posición Y';
            app.VelocidadYLabel_2 = uilabel(app.ProyectilSinFr_2);
            app.VelocidadYLabel_2.Text = 'Velocidad Y';
            app.velYcon = uieditfield(app.ProyectilSinFr_2, 'numeric');
            app.VelocidadXLabel_2 = uilabel(app.ProyectilSinFr_2);
            app.VelocidadXLabel_2.Text = 'Velocidad X';
            app.velXcon = uieditfield(app.ProyectilSinFr_2, 'numeric');
            app.posYcon = uieditfield(app.ProyectilSinFr_2, 'numeric');
            app.posXcon = uieditfield(app.ProyectilSinFr_2, 'numeric');
            app.PosicionZEditFieldLabel = uilabel(app.ProyectilSinFr_2);
            app.PosicionZEditFieldLabel.Text = 'Posicion Z';
            app.posZcon = uieditfield(app.ProyectilSinFr_2, 'numeric');
            app.VelocidadZEditFieldLabel_2 = uilabel(app.ProyectilSinFr_2);
            app.VelocidadZEditFieldLabel_2.Text = 'Velocidad Z';
            app.velZcon = uieditfield(app.ProyectilSinFr_2, 'numeric');
            app.kLabel = uilabel(app.UIFigure);
            app.kLabel.Text = 'k:';
            app.k = uieditfield(app.UIFigure, 'numeric');
            app.ngulodeDisparoLabel = uilabel(app.UIFigure);
            app.ngulodeDisparoLabel.Text = 'Ángulo en el plano:';
            app.slider = uislider(app.UIFigure);
            app.slider.Limits = [0 360];
            app.RetoMotordesimulacindemovimientoLabel = uilabel(app.UIFigure);
            app.RetoMotordesimulacindemovimientoLabel.Text = 'Reto: Motor de simulación de movimiento';
            app.MasaLabel = uilabel(app.UIFigure);
            app.MasaLabel.Text = 'Masa (kg):';
            app.masa1 = uieditfield(app.UIFigure, 'numeric');
            app.AlturaLabel = uilabel(app.UIFigure);
            app.AlturaLabel.Text = 'Altura del volcán:';
            app.altura = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulador

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end